classdef aerodynamics_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AerodynamicsLabel              matlab.ui.control.Label
        FlcheEditFieldLabel            matlab.ui.control.Label
        FlcheEditField                 matlab.ui.control.NumericEditField
        LiftCoefficientEditFieldLabel  matlab.ui.control.Label
        LiftCoefficientEditField       matlab.ui.control.NumericEditField
        DragCoefficientEditFieldLabel  matlab.ui.control.Label
        DragCoefficientEditField       matlab.ui.control.NumericEditField
        LiftBalanceEditFieldLabel      matlab.ui.control.Label
        LiftBalanceEditField           matlab.ui.control.NumericEditField
        DragHheEditFieldLabel          matlab.ui.control.Label
        DragHheEditField               matlab.ui.control.NumericEditField
        LiftCoefficientDRSEditFieldLabel  matlab.ui.control.Label
        LiftCoefficientDRSEditField    matlab.ui.control.NumericEditField
        DragCoefficientDRSEditFieldLabel  matlab.ui.control.Label
        DragCoefficientDRSEditField    matlab.ui.control.NumericEditField
        DRSCheckBox                    matlab.ui.control.CheckBox
        inmLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        invonFALabel                   matlab.ui.control.Label
        inmmLabel                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startFcn(app)
            app.FlcheEditField.Value = evalin('base', 'init.ae_A');
            app.LiftCoefficientEditField.Value = evalin('base', 'init.c_L');
            app.DragCoefficientEditField.Value = evalin('base', 'init.c_D');
            app.LiftBalanceEditField.Value = evalin('base', 'init.aeroBalance');
            app.DragHheEditField.Value = evalin('base', 'init.ae_z');
            app.DRSCheckBox.Value = evalin('base', 'init.DRS_active');
            app.LiftCoefficientDRSEditField.Value = evalin('base', 'init.c_L_DRS');
            app.DragCoefficientDRSEditField.Value = evalin('base', 'init.c_D_DRS');
        end

        % Value changed function: FlcheEditField
        function FlcheEditFieldValueChanged(app, event)
            value = app.FlcheEditField.Value;
            evalin('base', ['init.ae_A = ' num2str(value)]);
        end

        % Value changed function: LiftCoefficientEditField
        function LiftCoefficientEditFieldValueChanged(app, event)
            value = app.LiftCoefficientEditField.Value;
            evalin('base', ['init.c_L = ' num2str(value)]);
        end

        % Value changed function: DragCoefficientEditField
        function DragCoefficientEditFieldValueChanged(app, event)
            value = app.DragCoefficientEditField.Value;
            evalin('base', ['init.c_D = ' num2str(value)]);
        end

        % Value changed function: LiftBalanceEditField
        function LiftBalanceEditFieldValueChanged(app, event)
            value = app.LiftBalanceEditField.Value;
            evalin('base', ['init.aeroBalance = ' num2str(value)]);
        end

        % Value changed function: DragHheEditField
        function DragHheEditFieldValueChanged(app, event)
            value = app.DragHheEditField.Value;
            evalin('base', ['init.ae_z = ' num2str(value)]);
        end

        % Value changed function: DRSCheckBox
        function DRSCheckBoxValueChanged(app, event)
            value = app.DRSCheckBox.Value;
            evalin('base', ['init.DRS_active = ' num2str(value)]);
        end

        % Value changed function: LiftCoefficientDRSEditField
        function LiftCoefficientDRSEditFieldValueChanged(app, event)
            value = app.LiftCoefficientDRSEditField.Value;
            evalin('base', ['init.c_L_DRS = ' num2str(value)]);
        end

        % Value changed function: DragCoefficientDRSEditField
        function DragCoefficientDRSEditFieldValueChanged(app, event)
            value = app.DragCoefficientDRSEditField.Value;
            evalin('base', ['init.c_D_DRS = ' num2str(value)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AerodynamicsLabel = uilabel(app.UIFigure);
            app.AerodynamicsLabel.Text = 'Aerodynamics';
            app.FlcheEditFieldLabel = uilabel(app.UIFigure);
            app.FlcheEditFieldLabel.Text = 'Fläche';
            app.FlcheEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlcheEditField.ValueChangedFcn = createCallbackFcn(app, @FlcheEditFieldValueChanged, true);
            app.LiftCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.LiftCoefficientEditFieldLabel.Text = 'Lift Coefficient';
            app.LiftCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @LiftCoefficientEditFieldValueChanged, true);
            app.DragCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.DragCoefficientEditFieldLabel.Text = 'Drag Coefficient';
            app.DragCoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.DragCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @DragCoefficientEditFieldValueChanged, true);
            app.LiftBalanceEditFieldLabel = uilabel(app.UIFigure);
            app.LiftBalanceEditFieldLabel.Text = 'Lift Balance';
            app.LiftBalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftBalanceEditField.ValueChangedFcn = createCallbackFcn(app, @LiftBalanceEditFieldValueChanged, true);
            app.DragHheEditFieldLabel = uilabel(app.UIFigure);
            app.DragHheEditFieldLabel.Text = 'Drag Höhe';
            app.DragHheEditField = uieditfield(app.UIFigure, 'numeric');
            app.DragHheEditField.ValueChangedFcn = createCallbackFcn(app, @DragHheEditFieldValueChanged, true);
            app.LiftCoefficientDRSEditFieldLabel = uilabel(app.UIFigure);
            app.LiftCoefficientDRSEditFieldLabel.Text = 'Lift Coefficient DRS';
            app.LiftCoefficientDRSEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftCoefficientDRSEditField.ValueChangedFcn = createCallbackFcn(app, @LiftCoefficientDRSEditFieldValueChanged, true);
            app.DragCoefficientDRSEditFieldLabel = uilabel(app.UIFigure);
            app.DragCoefficientDRSEditFieldLabel.Text = 'Drag Coefficient DRS';
            app.DragCoefficientDRSEditField = uieditfield(app.UIFigure, 'numeric');
            app.DragCoefficientDRSEditField.ValueChangedFcn = createCallbackFcn(app, @DragCoefficientDRSEditFieldValueChanged, true);
            app.DRSCheckBox = uicheckbox(app.UIFigure);
            app.DRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DRSCheckBoxValueChanged, true);
            app.DRSCheckBox.Text = 'DRS';
            app.inmLabel = uilabel(app.UIFigure);
            app.inmLabel.Text = 'in m²';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.invonFALabel = uilabel(app.UIFigure);
            app.invonFALabel.Text = 'in % von FA';
            app.inmmLabel = uilabel(app.UIFigure);
            app.inmmLabel.Text = 'in mm ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aerodynamics_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end