classdef powertrain_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PowertrainLabel             matlab.ui.control.Label
        LeistungEditFieldLabel      matlab.ui.control.Label
        LeistungEditField           matlab.ui.control.NumericEditField
        HeizwertEditFieldLabel      matlab.ui.control.Label
        HeizwertEditField           matlab.ui.control.NumericEditField
        WirkungsgradEditFieldLabel  matlab.ui.control.Label
        WirkungsgradEditField       matlab.ui.control.NumericEditField
        AllradCheckBox              matlab.ui.control.CheckBox
        inkWLabel                   matlab.ui.control.Label
        inkWhLLabel                 matlab.ui.control.Label
        inLabel                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startFcn(app)
        app.LeistungEditField.Value = evalin('base','init.P_engine');
        app.HeizwertEditField.Value = evalin('base','init.Heizwert_fuel');
        app.WirkungsgradEditField.Value = evalin('base', ['init.engine_efficiency *' num2str(100)]);
        app.AllradCheckBox.Value = evalin('base', 'init.AllWD');
        end

        % Value changed function: LeistungEditField
        function changePower(app, event)
            value = app.LeistungEditField.Value;
            evalin('base',['init.P_engine = ' num2str(value)]);
        end

        % Callback function
        function closeFcn(app, event)
            delete(app)           
        end

        % Value changed function: HeizwertEditField
        function changeHeizwert(app, event)
            value = app.HeizwertEditField.Value;
            evalin('base',['init.Heizwert_fuel = ' num2str(value)]);
        end

        % Value changed function: WirkungsgradEditField
        function changeWirkungsgrad(app, event)
            value = app.WirkungsgradEditField.Value;
            evalin('base',['init.engine_efficiency = ' num2str(value/100)]);
        end

        % Value changed function: AllradCheckBox
        function changeAllrad(app, event)
            value = app.AllradCheckBox.Value;
            evalin('base',['init.AllWD = ' num2str(value)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PowertrainLabel = uilabel(app.UIFigure);
            app.PowertrainLabel.Text = 'Powertrain';
            app.LeistungEditFieldLabel = uilabel(app.UIFigure);
            app.LeistungEditFieldLabel.Text = 'Leistung';
            app.LeistungEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeistungEditField.ValueChangedFcn = createCallbackFcn(app, @changePower, true);
            app.LeistungEditField.Tooltip = {'in kW'};
            app.HeizwertEditFieldLabel = uilabel(app.UIFigure);
            app.HeizwertEditFieldLabel.Text = 'Heizwert';
            app.HeizwertEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeizwertEditField.ValueChangedFcn = createCallbackFcn(app, @changeHeizwert, true);
            app.HeizwertEditField.Tooltip = {'in kW'};
            app.WirkungsgradEditFieldLabel = uilabel(app.UIFigure);
            app.WirkungsgradEditFieldLabel.Text = 'Wirkungsgrad';
            app.WirkungsgradEditField = uieditfield(app.UIFigure, 'numeric');
            app.WirkungsgradEditField.ValueChangedFcn = createCallbackFcn(app, @changeWirkungsgrad, true);
            app.WirkungsgradEditField.Tooltip = {''};
            app.AllradCheckBox = uicheckbox(app.UIFigure);
            app.AllradCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeAllrad, true);
            app.AllradCheckBox.Text = 'Allrad ';
            app.inkWLabel = uilabel(app.UIFigure);
            app.inkWLabel.Text = 'in kW';
            app.inkWhLLabel = uilabel(app.UIFigure);
            app.inkWhLLabel.Text = 'in kWh/L';
            app.inLabel = uilabel(app.UIFigure);
            app.inLabel.Text = 'in %';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powertrain_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end